classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ServoSimulationAppUIFigure   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        VRCoefficientEditField       matlab.ui.control.NumericEditField
        VRCoefficientEditFieldLabel  matlab.ui.control.Label
        PIDTunerButton               matlab.ui.control.Button
        UsePreTunedValuesCheckBox    matlab.ui.control.CheckBox
        ShowT                        matlab.ui.control.CheckBox
        TfValueLabel                 matlab.ui.control.Label
        TransferFunctionLabel        matlab.ui.control.Label
        OpenSimulink3DButton         matlab.ui.control.Button
        SimulateButton               matlab.ui.control.Button
        kdEditField                  matlab.ui.control.NumericEditField
        kdEditField_2Label           matlab.ui.control.Label
        kiEditField                  matlab.ui.control.NumericEditField
        kiEditFieldLabel             matlab.ui.control.Label
        kpEditField                  matlab.ui.control.NumericEditField
        kpEditFieldLabel             matlab.ui.control.Label
        OpenSimulinkButton           matlab.ui.control.Button
        UsePIDCheckBox               matlab.ui.control.CheckBox
        AnimationCheckBox            matlab.ui.control.CheckBox
        VRModelCheckBox              matlab.ui.control.CheckBox
        StepValueButtonGroup         matlab.ui.container.ButtonGroup
        Step4                        matlab.ui.control.RadioButton
        Step3                        matlab.ui.control.RadioButton
        Step1                        matlab.ui.control.RadioButton
        UIpoles                      matlab.ui.control.UIAxes
        UIplot                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.ShowT.Visible=true;
            pidEnabled=app.UsePIDCheckBox.Value;
            animationEnabled=app.AnimationCheckBox.Value;
            vrmodelEnabled=app.VRModelCheckBox.Value;
            %variables to workspace
            servoSystem=ServoMotor.getSampleFunction;
            servoSystemPid=pidtune(servoSystem,'PID');
            stepValue=app.StepValueButtonGroup.SelectedObject.Text;
            assignin('base','servoSystem',servoSystem);
            assignin('base','servoSystemPid',servoSystemPid);
            assignin('base','stepValue',str2double(stepValue));
            opt = stepDataOptions('InputOffset',0,'StepAmplitude',str2double(stepValue));


            if(pidEnabled)
                %              step(feedback(servoSystem*servoSystemPid,1))
                kp=app.kpEditField.Value;
                ki=app.kiEditField.Value;
                kd=app.kdEditField.Value;
                servoSystemPid=pid(kp,ki,kd);
                servoSystem=feedback(servoSystem*servoSystemPid,1);
            end
            [amplitude,time]=step(servoSystem,opt);
            servoSystemPoles=pole(servoSystem);
            plot(app.UIpoles,real(servoSystemPoles),imag(servoSystemPoles),'r*');
            hold(app.UIpoles,'on');
            xlimit=app.UIpoles.XLim;
            ylimit=app.UIpoles.YLim;
           
            plot(app.UIpoles,xlimit,zeros(length(xlimit)),'--');
            plot(app.UIpoles,zeros(length(ylimit)),ylimit,'--');
            hold(app.UIpoles,'off');
            
            
            if(animationEnabled)
                h=plot(app.UIplot,time(1),amplitude(1));
                for i=1:length(time)
                    set(h,'XData',time(1:i),'YData',amplitude(1:i));
                    pause(0.01);
                end
            else
                plot(app.UIplot,time,amplitude)
            end
            
            hold(app.UIplot,'on');
            plot(app.UIplot,time,str2double(stepValue).*ones(length(time)));
            hold(app.UIplot,'off');
            if(vrmodelEnabled)
                world=vrworld('vrservo.x3d');
                open(world);
                fig=view(world,'-internal');
                vrdrawnow;
                pointer=vrnode(world,'Pointer');
                rotor=vrnode(world,'rotor1');
                circle_color=vrnode(world,'red_circle_material');
                responseFig=figure('Name','Step Response');
                degcoeff=app.VRCoefficientEditField.Value;
                radcoeff=deg2rad(degcoeff);
                plot(time,amplitude);
                hold on
                resPlot=plot(time(1),amplitude(1),'r*');
                [audio, fs] = audioread("Servo Sound Effects Avosound.wav");
                    sound(audio, fs*(length(time))/100);
                  
                pointer.rotation=[0,0,1,amplitude(length(time))*radcoeff];
                for i=1:length(time)
                    
                    set(resPlot,'XData',time(1:i),'YData',amplitude(1:i));
                    rotor.rotation=[0,0,1,amplitude(i)*radcoeff];
                    vrdrawnow;
                    if(isequal(rotor.rotation,pointer.rotation))
                        circle_color.diffuseColor=[0 1 0];
                    else
                        circle_color.diffuseColor=[1 0 0];
                    end
                    pause(0.001);
                end
                hold off;
                pause(0.5);
                close(fig);
                close(responseFig);
            else
                step(servoSystem,opt);
            end
        end

        % Value changed function: UsePIDCheckBox
        function UsePIDCheckBoxValueChanged(app, event)
            value = app.UsePIDCheckBox.Value;
            if(value)
                flagp=true;
                app.kpEditField.Visible=flagp;
                app.kdEditField.Visible=flagp;
                app.kiEditField.Visible=flagp;
                app.kpEditFieldLabel.Visible=flagp;
                app.kdEditField_2Label.Visible=flagp;
                app.kiEditFieldLabel.Visible=flagp;
                
                app.UsePreTunedValuesCheckBox.Visible=flagp;
                
                app.kpEditField.Value=0;
                app.kdEditField.Value=0;
                app.kiEditField.Value=0;
                
            else
                flagp=false;
                app.kpEditField.Visible=flagp;
                app.kdEditField.Visible=flagp;
                app.kiEditField.Visible=flagp;
                app.kpEditFieldLabel.Visible=flagp;
                app.kdEditField_2Label.Visible=flagp;
                app.kiEditFieldLabel.Visible=flagp;
                app.UsePreTunedValuesCheckBox.Visible=flagp;
            end
        end

        % Button pushed function: OpenSimulinkButton
        function OpenSimulinkButtonPushed(app, event)
            servo_simulink;
        end

        % Button pushed function: OpenSimulink3DButton
        function OpenSimulink3DButtonPushed(app, event)
            vrservo_simulink3d;
        end

        % Value changed function: ShowT
        function ShowTValueChanged(app, event)
            value = app.ShowT.Value;
            if(value)
                servoSystem=ServoMotor.getSampleFunction;
                s_num=servoSystem.num{1};
                s_den=servoSystem.den{1};
                tfstr=sprintf('%.f/(%1.2f*s^3+%2.f*s^2+%3.1f*s+%.f)',s_num(length(s_num)),s_den(1),s_den(2),s_den(3),s_den(4));
                app.TfValueLabel.Visible=true;
                app.TransferFunctionLabel.Visible=true;
                app.TfValueLabel.Text=tfstr;
            else
                app.TfValueLabel.Visible=false;
                app.TransferFunctionLabel.Visible=false;
            end
        end

        % Button pushed function: PIDTunerButton
        function PIDTunerButtonPushed(app, event)
            servoSystem=ServoMotor.getSampleFunction;
            pidTuner(servoSystem,'PID');
        end

        % Value changed function: UsePreTunedValuesCheckBox
        function UsePreTunedValuesCheckBoxValueChanged(app, event)
            value = app.UsePreTunedValuesCheckBox.Value;
            if(value)
                servoSystem=ServoMotor.getSampleFunction;
                servoSystemPid=pidtune(servoSystem,'PID');
                app.kpEditField.Value=2.1906;
                app.kiEditField.Value=9.9340;
                app.kdEditField.Value=0.1208;
                app.kpEditField.Value=servoSystemPid.kp;
                app.kiEditField.Value=servoSystemPid.ki;
                app.kdEditField.Value=servoSystemPid.kd;
            end
        end

        % Value changed function: VRModelCheckBox
        function VRModelCheckBoxValueChanged(app, event)
            value = app.VRModelCheckBox.Value;
            if(value)
                app.VRCoefficientEditField.Visible=true;
                
                app.VRCoefficientEditFieldLabel.Visible=true;
            else
                app.VRCoefficientEditField.Visible=false;
                app.VRCoefficientEditFieldLabel.Visible=false; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ServoSimulationAppUIFigure = uifigure('Visible', 'off');
            app.ServoSimulationAppUIFigure.Name = 'Servo Simulation App';
            app.GridLayout = uigridlayout(app.ServoSimulationAppUIFigure);
            app.UIplot = uiaxes(app.GridLayout);
            title(app.UIplot, 'Step Response')
            xlabel(app.UIplot, 'time')
            ylabel(app.UIplot, 'Amplitude')
            app.UIpoles = uiaxes(app.GridLayout);
            title(app.UIpoles, 'Poles')
            xlabel(app.UIpoles, 'Real')
            ylabel(app.UIpoles, 'Image')
            app.StepValueButtonGroup = uibuttongroup(app.GridLayout);
            app.StepValueButtonGroup.Title = 'Step Value';
            app.Step1 = uiradiobutton(app.StepValueButtonGroup);
            app.Step1.Text = '1';
            app.Step1.Value = true;
            app.Step3 = uiradiobutton(app.StepValueButtonGroup);
            app.Step3.Text = '3';
            app.Step4 = uiradiobutton(app.StepValueButtonGroup);
            app.Step4.Text = '5';
            app.VRModelCheckBox = uicheckbox(app.GridLayout);
            app.VRModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @VRModelCheckBoxValueChanged, true);
            app.VRModelCheckBox.Text = 'VR Model';
            app.AnimationCheckBox = uicheckbox(app.GridLayout);
            app.AnimationCheckBox.Text = 'Animation';
            app.UsePIDCheckBox = uicheckbox(app.GridLayout);
            app.UsePIDCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsePIDCheckBoxValueChanged, true);
            app.UsePIDCheckBox.Text = 'Use PID';
            app.OpenSimulinkButton = uibutton(app.GridLayout, 'push');
            app.OpenSimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSimulinkButtonPushed, true);
            app.OpenSimulinkButton.Text = 'Open Simulink';
            app.kpEditFieldLabel = uilabel(app.GridLayout);
            app.kpEditFieldLabel.Visible = 'off';
            app.kpEditFieldLabel.Text = 'kp';
            app.kpEditField = uieditfield(app.GridLayout, 'numeric');
            app.kpEditField.Visible = 'off';
            app.kiEditFieldLabel = uilabel(app.GridLayout);
            app.kiEditFieldLabel.Visible = 'off';
            app.kiEditFieldLabel.Text = 'ki';
            app.kiEditField = uieditfield(app.GridLayout, 'numeric');
            app.kiEditField.Visible = 'off';
            app.kdEditField_2Label = uilabel(app.GridLayout);
            app.kdEditField_2Label.Visible = 'off';
            app.kdEditField_2Label.Text = 'kd';
            app.kdEditField = uieditfield(app.GridLayout, 'numeric');
            app.kdEditField.Visible = 'off';
            app.SimulateButton = uibutton(app.GridLayout, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.OpenSimulink3DButton = uibutton(app.GridLayout, 'push');
            app.OpenSimulink3DButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSimulink3DButtonPushed, true);
            app.OpenSimulink3DButton.Text = 'Open Simulink3D';
            app.TransferFunctionLabel = uilabel(app.GridLayout);
            app.TransferFunctionLabel.Visible = 'off';
            app.TransferFunctionLabel.Text = 'Transfer Function =';
            app.TfValueLabel = uilabel(app.GridLayout);
            app.TfValueLabel.Visible = 'off';
            app.TfValueLabel.Text = '';
            app.ShowT = uicheckbox(app.GridLayout);
            app.ShowT.ValueChangedFcn = createCallbackFcn(app, @ShowTValueChanged, true);
            app.ShowT.Text = 'Show Transfer Function';
            app.UsePreTunedValuesCheckBox = uicheckbox(app.GridLayout);
            app.UsePreTunedValuesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsePreTunedValuesCheckBoxValueChanged, true);
            app.UsePreTunedValuesCheckBox.Visible = 'off';
            app.UsePreTunedValuesCheckBox.Text = 'Use Pre-Tuned Values';
            app.PIDTunerButton = uibutton(app.GridLayout, 'push');
            app.PIDTunerButton.ButtonPushedFcn = createCallbackFcn(app, @PIDTunerButtonPushed, true);
            app.PIDTunerButton.Text = 'PID Tuner';
            app.VRCoefficientEditFieldLabel = uilabel(app.GridLayout);
            app.VRCoefficientEditFieldLabel.Visible = 'off';
            app.VRCoefficientEditFieldLabel.Text = 'VR Coefficient';
            app.VRCoefficientEditField = uieditfield(app.GridLayout, 'numeric');
            app.VRCoefficientEditField.Visible = 'off';
            app.VRCoefficientEditField.Value = 25;
            % Show the figure after all components are created
            app.ServoSimulationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ServoSimulationAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ServoSimulationAppUIFigure)
        end
    end
end